classdef Lab6_Car_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        StopButton            matlab.ui.control.Button
        DriveuntilLineButton  matlab.ui.control.Button
        DriveuntilWallButton  matlab.ui.control.Button
        StreamingPanel        matlab.ui.container.Panel
        TimeTimestampLabel    matlab.ui.control.Label
        RightLamp             matlab.ui.control.Lamp
        RightLampLabel        matlab.ui.control.Label
        MiddleLamp            matlab.ui.control.Lamp
        MiddleLampLabel       matlab.ui.control.Label
        LeftLamp              matlab.ui.control.Lamp
        LeftLampLabel         matlab.ui.control.Label
        StreamingSwitch       matlab.ui.control.Switch
        StreamingSwitchLabel  matlab.ui.control.Label
        BuzzerButton          matlab.ui.control.Button
        SpeedSlider           matlab.ui.control.Slider
        SpeedSliderLabel      matlab.ui.control.Label
        Label                 matlab.ui.control.Label
    end

    
     properties (Access = private)
        mqClient
        priorKey = ''
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            fprintf("Recieved: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type
            
            if ~isempty(messageStruct.payload)
                messagePayload = messageStruct.payload;
            end

            if strcmp(messageType, "line_sensor_reading")

                messagePayload = messageStruct.payload
                app.TimeTimestampLabel.Text = messagePayload.timestamp;
                app.LeftLamp.Color = messagePayload.left;
                app.MiddleLamp.Color = messagePayload.middle;
                app.RightLamp.Color = messagePayload.right;
            end

        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            if nargin > 2
                messageStruct.payload = messagePayload;
            end
            messageJson = jsonencode(messageStruct);

            write(app.mqClient, "me435/eckelbge/to_pi",messageJson)
            fprintf("Sent: %s\n",messageJson)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/eckelbge/#", "Callback", @app.myCallback)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, app.priorKey)
                return; % The key is being held down ignore this one
            end
            app.priorKey = key;

            speed = round(app.SpeedSlider.Value);

            if strcmp(key,"uparrow")
                app.sendMessage("set_speeds",[speed speed speed speed])
            end

            if strcmp(key,"downarrow")
                app.sendMessage("set_speeds",[-speed -speed -speed -speed])
            end

            if strcmp(key,"leftarrow")
                app.sendMessage("set_speeds",[-speed speed speed -speed])
            end

            if strcmp(key,"rightarrow")
                app.sendMessage("set_speeds",[speed -speed -speed speed])
            end

            if strcmp(key,"a")
                app.sendMessage("set_speeds",[-speed -speed speed speed])
            end

            if strcmp(key,"d")
                app.sendMessage("set_speeds",[speed speed -speed -speed])
            end

        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            app.priorKey = "";
            app.sendMessage("stop")
            
        end

        % Button pushed function: BuzzerButton
        function BuzzerButtonPushed(app, event)
            app.sendMessage("beep")
        end

        % Button pushed function: DriveuntilWallButton
        function DriveuntilWallButtonPushed(app, event)
            app.sendMessage("mode","drive_until_wall")
        end

        % Button pushed function: DriveuntilLineButton
        function DriveuntilLineButtonPushed(app, event)
            app.sendMessage("mode","drive_until_line")
        end

        % Value changed function: StreamingSwitch
        function StreamingSwitchValueChanged(app, event)
            value = app.StreamingSwitch.Value;
            
            if strcmp(value,"On")
                app.sendMessage("is_streaming",true)
            else
                app.sendMessage("is_streaming",false)
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.sendMessage("mode","none")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Click in the area below to use the keyboard.';
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Value = 40;
            app.BuzzerButton = uibutton(app.UIFigure, 'push');
            app.BuzzerButton.ButtonPushedFcn = createCallbackFcn(app, @BuzzerButtonPushed, true);
            app.BuzzerButton.Text = 'Buzzer';
            app.StreamingPanel = uipanel(app.UIFigure);
            app.StreamingPanel.Title = 'Streaming';
            app.StreamingSwitchLabel = uilabel(app.StreamingPanel);
            app.StreamingSwitchLabel.Text = 'Switch';
            app.StreamingSwitch = uiswitch(app.StreamingPanel, 'slider');
            app.StreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamingSwitchValueChanged, true);
            app.LeftLampLabel = uilabel(app.StreamingPanel);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.StreamingPanel);
            app.MiddleLampLabel = uilabel(app.StreamingPanel);
            app.MiddleLampLabel.Text = 'Middle';
            app.MiddleLamp = uilamp(app.StreamingPanel);
            app.RightLampLabel = uilabel(app.StreamingPanel);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.StreamingPanel);
            app.TimeTimestampLabel = uilabel(app.StreamingPanel);
            app.TimeTimestampLabel.Text = 'Time Timestamp';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.DriveuntilWallButton = uibutton(app.Panel, 'push');
            app.DriveuntilWallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveuntilWallButtonPushed, true);
            app.DriveuntilWallButton.Text = 'Drive until Wall';
            app.DriveuntilLineButton = uibutton(app.Panel, 'push');
            app.DriveuntilLineButton.ButtonPushedFcn = createCallbackFcn(app, @DriveuntilLineButtonPushed, true);
            app.DriveuntilLineButton.Text = 'Drive until Line';
            app.StopButton = uibutton(app.Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab6_Car_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end